classdef cherryroom < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CherryRoom                 matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        SignalsPanel               matlab.ui.container.Panel
        XSignalPanel               matlab.ui.container.Panel
        LoadButton1                matlab.ui.control.Button
        FilePathEditField_3Label   matlab.ui.control.Label
        FilePathEditField1         matlab.ui.control.EditField
        YSignalPanel               matlab.ui.container.Panel
        LoadButton2                matlab.ui.control.Button
        FilePathEditField_4Label   matlab.ui.control.Label
        FilePathEditField2         matlab.ui.control.EditField
        CoherenceEstimationButton  matlab.ui.control.Button
        ParametersPanel            matlab.ui.container.Panel
        WindowLengthDropDownLabel  matlab.ui.control.Label
        WindowLengthDropDown       matlab.ui.control.DropDown
        SmoothFactorSliderLabel    matlab.ui.control.Label
        SmoothFactorSlider         matlab.ui.control.Slider
        CherryRoomLabel            matlab.ui.control.Label
        InterauralCoherenceEstimatorLabel  matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        Button                     matlab.ui.control.Button
        RightPanel                 matlab.ui.container.Panel
        GraphsPanel                matlab.ui.container.Panel
        MagAxes                    matlab.ui.control.UIAxes
        PhaseAxes                  matlab.ui.control.UIAxes
        InstructionsPanel          matlab.ui.container.Panel
        TextArea                   matlab.ui.control.TextArea
        GotitButton                matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        x = zeros(1,10000);
        y = zeros(1,10000);
        image = imread('Resources\logo.png');
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.CherryRoom.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {524, 524};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {430, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: LoadButton1
        function LoadButton1Pushed(app, event)
            [file, path] = uigetfile('*.wav');
            if isequal(file, 0)
                app.FilePathEditField1.Value = 'No file selected';
            else
                app.FilePathEditField1.Value = fullfile(path,file);
                app.x = audioread(file);
                [~, columns] = size(app.x);
                if (columns >= 2)
                    f = errordlg('Imported file is not mono. Please insert a mono audio file.','File Error');
                end
            end
        end

        % Button pushed function: LoadButton2
        function LoadButton2Pushed(app, event)
            [file, path] = uigetfile('*.wav');
            if isequal(file, 0)
                app.FilePathEditField2.Value = 'No file selected';
            else
                app.FilePathEditField2.Value = fullfile(path,file);
                app.y = audioread(file);
                [~, columns] = size(app.y);
                if (columns >= 2)
                    f = errordlg('Imported file is not mono. Please insert a mono audio file.','File Error');
                end
            end
        end

        % Button pushed function: CoherenceEstimationButton
        function CoherenceEstimationButtonPushed(app, event)
            if or(strcmp(app.FilePathEditField1.Value, 'No File Selected'), strcmp(app.FilePathEditField2.Value, 'No File Selected')) 
                f = errordlg('Import 2 valid files.', 'File Error');
            else
                l_win = str2double(app.WindowLengthDropDown.Value);
                window = hann(l_win);
                overlap = 0.75*l_win;
                offset = l_win - overlap;
                %n_frames = ceil((N-overlap)/offset);
            
                nff = l_win*2-1;
                X = spectrogram(app.x, window, overlap, nff);
                Y = spectrogram(app.y, window, overlap, nff);
                [l_win, n_frames] = size(X);
            
                a = app.SmoothFactorSlider.Value; % Smoothing factor
                epsilon = 10e-12; % A very small value for not getting zero on denominator of coherence equation
                PXX = zeros(l_win, n_frames);
                PYY = zeros(l_win, n_frames);
                PXY = zeros(l_win, n_frames);
                COH = zeros(l_win, n_frames);

                % For the first frame:
                PXX(:,1) = abs(X(:,1)).^2;
                PYY(:,1) = abs(Y(:,1)).^2;
                PXY(:,1) = X(:,1).*conj(Y(:,1));
                COH(:,1) = PXY(:,1)./sqrt(PXX(:,1).*PYY(:,1)+epsilon);

                % For each frame:
                for n=2:n_frames
                    PXX(:,n) = a.*PXX(:,n-1)+(1-a).*abs(X(:,n)).^2;
                    PYY(:,n) = a.*PYY(:,n-1)+(1-a).*abs(Y(:,n)).^2;
                    PXY(:,n) = a.*PXY(:,n-1)+(1-a).*X(:,n).*conj(Y(:,n));
                    COH(:,n) = PXY(:,n)./sqrt((PXX(:,n).*PYY(:,n))+epsilon);
                end
            
                coherence = zeros(n_frames,1);
                for n = 1:n_frames
                    coherence(n,1) = COH(l_win,n); 
                end

                MSC = abs(coherence).^2;
                phase_coh = angle(coherence);
                plot(app.MagAxes, MSC, 'k');
                plot(app.PhaseAxes, unwrap(phase_coh),'k');
            end

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.InstructionsPanel.Visible = 'on';
        end

        % Button pushed function: GotitButton
        function GotitButtonPushed(app, event)
            app.InstructionsPanel.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CherryRoom = uifigure('Visible', 'off');
            app.CherryRoom.Name = 'Cherry Room';
            app.CherryRoom.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.CherryRoom);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SignalsPanel = uipanel(app.LeftPanel);
            app.SignalsPanel.Title = 'Signals';
            app.XSignalPanel = uipanel(app.SignalsPanel);
            app.XSignalPanel.Title = 'X Signal';
            app.LoadButton1 = uibutton(app.XSignalPanel, 'push');
            app.LoadButton1.ButtonPushedFcn = createCallbackFcn(app, @LoadButton1Pushed, true);
            app.LoadButton1.Icon = 'load file.png';
            app.LoadButton1.Text = '';
            app.FilePathEditField_3Label = uilabel(app.XSignalPanel);
            app.FilePathEditField_3Label.Text = 'File Path';
            app.FilePathEditField1 = uieditfield(app.XSignalPanel, 'text');
            app.FilePathEditField1.Value = 'No File Selected';
            app.YSignalPanel = uipanel(app.SignalsPanel);
            app.YSignalPanel.Title = 'Y Signal';
            app.LoadButton2 = uibutton(app.YSignalPanel, 'push');
            app.LoadButton2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton2Pushed, true);
            app.LoadButton2.Icon = 'load file.png';
            app.LoadButton2.Text = '';
            app.FilePathEditField_4Label = uilabel(app.YSignalPanel);
            app.FilePathEditField_4Label.Text = 'File Path';
            app.FilePathEditField2 = uieditfield(app.YSignalPanel, 'text');
            app.FilePathEditField2.Value = 'No File Selected';
            app.CoherenceEstimationButton = uibutton(app.SignalsPanel, 'push');
            app.CoherenceEstimationButton.ButtonPushedFcn = createCallbackFcn(app, @CoherenceEstimationButtonPushed, true);
            app.CoherenceEstimationButton.Text = 'Coherence Estimation';
            app.ParametersPanel = uipanel(app.SignalsPanel);
            app.ParametersPanel.Title = 'Parameters';
            app.WindowLengthDropDownLabel = uilabel(app.ParametersPanel);
            app.WindowLengthDropDownLabel.Text = 'Window Length';
            app.WindowLengthDropDown = uidropdown(app.ParametersPanel);
            app.WindowLengthDropDown.Items = {'512', '1024', '2048'};
            app.WindowLengthDropDown.Value = '512';
            app.SmoothFactorSliderLabel = uilabel(app.ParametersPanel);
            app.SmoothFactorSliderLabel.Text = 'Smooth Factor';
            app.SmoothFactorSlider = uislider(app.ParametersPanel);
            app.SmoothFactorSlider.Limits = [0 1];
            app.SmoothFactorSlider.Value = 0.5;
            app.CherryRoomLabel = uilabel(app.LeftPanel);
            app.CherryRoomLabel.Text = 'Cherry Room';
            app.InterauralCoherenceEstimatorLabel = uilabel(app.LeftPanel);
            app.InterauralCoherenceEstimatorLabel.Text = 'Interaural Coherence Estimator';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'logo.png';
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '?';
            app.RightPanel = uipanel(app.GridLayout);
            app.GraphsPanel = uipanel(app.RightPanel);
            app.GraphsPanel.Title = 'Graphs';
            app.MagAxes = uiaxes(app.GraphsPanel);
            title(app.MagAxes, 'Coherence Magnitude')
            xlabel(app.MagAxes, 'Frequency (Hz)')
            ylabel(app.MagAxes, '|Coh|')
            app.MagAxes.XLim = [10 20000];
            app.MagAxes.XTick = [10 100 1000 10000];
            app.MagAxes.XTickLabel = {'10^{1}'; '10^{2}'; '10^{3}'; '10^{4}'};
            app.MagAxes.XScale = 'log';
            app.PhaseAxes = uiaxes(app.GraphsPanel);
            title(app.PhaseAxes, 'Phase Coherence')
            xlabel(app.PhaseAxes, 'Frequency (Hz)')
            ylabel(app.PhaseAxes, 'Phase (rad)')
            app.PhaseAxes.XLim = [10 20000];
            app.PhaseAxes.YLim = [-100 400];
            app.PhaseAxes.XScale = 'log';
            app.InstructionsPanel = uipanel(app.GraphsPanel);
            app.InstructionsPanel.Title = 'Instructions';
            app.InstructionsPanel.Visible = 'off';
            app.GotitButton = uibutton(app.InstructionsPanel, 'push');
            app.GotitButton.ButtonPushedFcn = createCallbackFcn(app, @GotitButtonPushed, true);
            app.GotitButton.Text = 'Got it';
            app.TextArea = uitextarea(app.InstructionsPanel);
            app.TextArea.Value = {'"Cherry Room" is a GUI using an iterrative method for calculating Coherence Function between two signals. Coherence is a complex statistic parameter that is expressing the similarity between the input signals.'; ''; 'After you put the input signals the algorithm calculates the FFT and and divides the signals into frames. Then a window filter is applied to each frame and finally auto and cross Power Spectral Density is calculated. After the Coherence Function is estimated it will be plotted in the right graphs panel.'; ''; 'Load the audio signals of your desire in the signals panel, choose the length of the window filter, choose the smoothing factor to determine how much of the previous frame the algorithm takes into account for the current frame. Then the graphs of the magitude and phase of Coherence Function are presented at the right panel.'};
            % Show the figure after all components are created
            app.CherryRoom.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cherryroom

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CherryRoom)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CherryRoom)
        end
    end
end